classdef MyoWave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyoWaveUIFigure  matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        TransformingelectricalsignalsintomotionLabel  matlab.ui.control.Label
        GridLayout2      matlab.ui.container.GridLayout
        LoadDataButton   matlab.ui.control.Button
        MyoWaveLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainPage % Variable to connect to the main page.
        appName = "MyoWave" % Name of this matlab app file.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize');
            appWidth = 1200;
            appHeight = 700;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MyoWaveUIFigure.Position = [appX appY appWidth appHeight];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

           % dialog box to select a zip file.
            [file, path] = uigetfile('*.zip', 'Select a zip file');          
            selectedZipFile = fullfile(path, file);              
            delim = '\';
            app.MyoWaveUIFigure.Visible = "off";

            if isequal(file, 0)
                app.MyoWaveUIFigure.Visible = "on";
            else
                % create a dataObject strucutre which holds important
                % information for all the matlab app files.
                dataObject.delim = delim;
                dataObject.selectedZipFile = selectedZipFile;
                app.mainPage = main_page(app, dataObject);
                delete(app.MyoWaveUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyoWaveUIFigure = uifigure('Visible', 'off');
            app.MyoWaveUIFigure.Name = 'MyoWave';
            app.GridLayout = uigridlayout(app.MyoWaveUIFigure);
            app.MyoWaveLabel = uilabel(app.GridLayout);
            app.MyoWaveLabel.Text = 'MyoWave';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TransformingelectricalsignalsintomotionLabel = uilabel(app.GridLayout);
            app.TransformingelectricalsignalsintomotionLabel.Text = 'Transforming electrical signals into motion!';
            % Show the figure after all components are created
            app.MyoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyoWave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyoWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyoWaveUIFigure)
        end
    end
end