classdef data_preprocessing_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyoWaveUIFigure            matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        NextButton                 matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        HandPositionDropDown       matlab.ui.control.DropDown
        HandPositionDropDownLabel  matlab.ui.control.Label
        SubjectDropDown            matlab.ui.control.DropDown
        SubjectDropDownLabel       matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout8                matlab.ui.container.GridLayout
        ChoosefilterButtonGroup    matlab.ui.container.ButtonGroup
        MovmeanButton              matlab.ui.control.RadioButton
        ButterworthButton          matlab.ui.control.RadioButton
        GridLayout6                matlab.ui.container.GridLayout
        PreprocessingButton        matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        GridLayout2                matlab.ui.container.GridLayout
        ProcessedDataLabel         matlab.ui.control.Label
        RawDataLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        dataObject
        subjects = 40;
        numHandPositions = 10;
        samplingFreq = 2000;
        channelDataSize = 12000;  % size of data points in a single channel for a single subject and position.
        currentSubject = 1; % Subject 1 is the selected default subject.
        currentHandPosition = "hand_rest"; % Hand Rest is the selected default hand position.
        currentFilter = "Butterworth"; % Butterworth filter is the selected default filter.
        currentRawData % Current subject and hand position raw data.
        currentPreprocessedData % Current subject and hand position preprocessed data.
        rawData % Entire raw data of all subjects and hand positions.
        isPreprocessed % Variable to check if preprocessing has been done.
   end
    
    properties (Access = public)
        mainPage 
        appName = "dataPreprocessingPage"
    end
    
    methods (Access = private)
        % Plot for both the raw and preprocessed data subject and position
        % wise.
        function plotData(app, axes, data)
            % Custom title name to fix the issue of display of '_' in GUI.
            if strcmp(string(app.currentHandPosition), "hand_rest")
                titleName = "Subject " + string(app.currentSubject) + " Hand Rest Position";
            elseif strcmp(string(app.currentHandPosition), "ulnar_deviation")
                titleName = "Subject " + string(app.currentSubject) + " Ulnar Deviation Position";
            elseif strcmp(string(app.currentHandPosition), "radial_deviation")
                titleName = "Subject " + string(app.currentSubject) + " Radial Deviation Position";
            else
                titleName = "Subject " + string(app.currentSubject) + " " + string(app.currentHandPosition) + " Position";
            end

            title(axes, titleName);
            app.dataObject.time = linspace(0, app.samplingFreq, app.channelDataSize);
            plot(axes, app.dataObject.time, data', LineWidth=2)
            legend(axes, 'Ch1', 'Ch2', 'Ch3', 'Ch4')
        end
        
        % function for preprocessing.
        function preprocessedData = preProcessing(app, rawData)
            windowSize = 100; 
            preprocessedData = rawData;
            % Movmean filter technique.
            if strcmp(app.currentFilter, 'Movmean') 
                preprocessedData(:, 1) = movmean(rawData(:, 1),windowSize);
                preprocessedData(:, 2) = movmean(rawData(:, 2),windowSize);
                preprocessedData(:, 3) = movmean(rawData(:, 3),windowSize);
                preprocessedData(:, 4) = movmean(rawData(:, 4),windowSize);
            else
                % Butterworth filter technique.
                Fc = 50; % cutoff frequency
                n = 4; % 4th order
                [b, a] = butter(n,Fc/(app.samplingFreq/2));
                preprocessedData(:, 1) = filtfilt(b,a,rawData(:, 1));
                preprocessedData(:, 2) = filtfilt(b,a,rawData(:, 2));
                preprocessedData(:, 3) = filtfilt(b,a,rawData(:, 3));
                preprocessedData(:, 4) = filtfilt(b,a,rawData(:, 4));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, dataObject)
            screenSize = get(groot, 'ScreenSize');
            appWidth = 1200;
            appHeight = 700;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MyoWaveUIFigure.Position = [appX appY appWidth appHeight];

            app.dataObject = dataObject;       
            app.dataObject.subjects = app.subjects;
            app.dataObject.numHandPositions = app.numHandPositions;
            app.dataObject.channelDataSize = app.channelDataSize;
            
            % default file to display the plots
            default_raw_file_name = 'processed' + string(app.dataObject.delim) + '1_hand_rest.mat';
            
            for idx = 2:length(app.dataObject.files)
                filename = app.dataObject.files{1, idx};
                
                % Splitting file name to extract subejct Id and hand
                % position
                splitFolderValues = convertCharsToStrings(split(filename, app.dataObject.delim));
                splitSubjectIdValues = split(splitFolderValues(2, 1), "_");

                % Fixing if there are more than 2 splitted string in case
                % of eg. 35_ulnar_deviation.mat
                if length(splitSubjectIdValues) == 2          
                    subjectId = int16(str2num(splitSubjectIdValues(1)));
                    splitHandPosition = split(splitSubjectIdValues(2,1), ".");
                    handPosition = splitHandPosition(1, 1);
                else
                    subjectId = int16(str2num(splitSubjectIdValues(1)));
                    splitHandPosition = split(splitSubjectIdValues(3,1), ".");
                    handPosition = splitSubjectIdValues(2,1) + "_" + splitHandPosition(1, 1);
                end
                app.rawData(subjectId).(handPosition) = load(filename).data;
            
                % App behavior when the default file is selected
                if strcmp(app.dataObject.files(idx), default_raw_file_name)
                    app.currentRawData = load(filename).data;
                    plotData(app, app.UIAxes, app.currentRawData);
                end
            end
        end

        % Callback function: SubjectDropDown, UIAxes
        function SubjectDropDownValueChanged(app, event)
            % Extracting subject id from the subject drop down value
            value = app.SubjectDropDown.Value;
            split_values = split(value, " ");
            subjectId = split_values(2, 1);
            app.currentSubject = int16(str2num(cell2mat(subjectId)));

            % extracting raw data based on the extracted subject id 
            app.currentRawData = app.rawData(app.currentSubject).(app.currentHandPosition);

            if app.isPreprocessed
                plotData(app, app.UIAxes, app.currentRawData);
                app.currentPreprocessedData = preProcessing(app, app.currentRawData);
                plotData(app, app.UIAxes2, app.currentPreprocessedData);
            else
                % Changing the title of the preProcessed Data UI axes
                % whenever Subject drop down value changes.
                if strcmp(string(app.currentHandPosition), "hand_rest")
                    titleName = "Subject " + string(app.currentSubject) + " Hand Rest Position";
                elseif strcmp(string(app.currentHandPosition), "ulnar_deviation")
                    titleName = "Subject " + string(app.currentSubject) + " Ulnar Deviation Position";
                elseif strcmp(string(app.currentHandPosition), "radial_deviation")
                    titleName = "Subject " + string(app.currentSubject) + " Radial Deviation Position";
                else
                    titleName = "Subject " + string(app.currentSubject) + " " + string(app.currentHandPosition) + " Position";
                end
                title(app.UIAxes2, titleName);
                plotData(app, app.UIAxes, app.currentRawData);
            end
        end

        % Selection changed function: ChoosefilterButtonGroup
        function ChoosefilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosefilterButtonGroup.SelectedObject;
            app.currentFilter = selectedButton.Text;
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            app.isPreprocessed = true;
            app.NextButton.Enable = "on";
            app.currentPreprocessedData = preProcessing(app, app.currentRawData);
            plotData(app, app.UIAxes2, app.currentPreprocessedData);
            
            % Preprocessing the rest of the raw data for which the subject
            % and hand position is not selected. This will ensure that with
            % every preprocessing step the entire raw data is preprocess again. 
            handPositionNames = fieldnames(app.rawData);
            for subjectId = 1:app.subjects
                for handPosition = 1:app.numHandPositions
                    currentData = app.rawData(subjectId).(handPositionNames{handPosition});
                    app.dataObject.processedData(subjectId).(handPositionNames{handPosition}) = preProcessing(app, currentData);
                end
            end
        end

        % Value changed function: HandPositionDropDown
        function HandPositionDropDownValueChanged(app, event)
            % Extracting hand position from the subject drop down value
            value = app.HandPositionDropDown.Value;
            app.currentHandPosition = string(value);
            app.currentRawData = app.rawData(app.currentSubject).(app.currentHandPosition);

            if app.isPreprocessed
                plotData(app, app.UIAxes, app.currentRawData);
                app.currentPreprocessedData = preProcessing(app, app.currentRawData);
                plotData(app, app.UIAxes2, app.currentPreprocessedData);
            else
                % Changing the title of the preProcessed Data UI axes
                % whenever Subject drop down value changes.
                if strcmp(string(app.currentHandPosition), "hand_rest")
                    titleName = "Subject " + string(app.currentSubject) + " Hand Rest Position";
                elseif strcmp(string(app.currentHandPosition), "ulnar_deviation")
                    titleName = "Subject " + string(app.currentSubject) + " Ulnar Deviation Position";
                elseif strcmp(string(app.currentHandPosition), "radial_deviation")
                    titleName = "Subject " + string(app.currentSubject) + " Radial Deviation Position";
                else
                    titleName = "Subject " + string(app.currentSubject) + " " + string(app.currentHandPosition) + " Position";
                end
                title(app.UIAxes2, titleName);
                plotData(app, app.UIAxes, app.currentRawData);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.mainPage = main_page(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyoWaveUIFigure = uifigure('Visible', 'off');
            app.MyoWaveUIFigure.Name = 'MyoWave';
            app.GridLayout = uigridlayout(app.MyoWaveUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RawDataLabel = uilabel(app.GridLayout2);
            app.RawDataLabel.Text = 'Raw Data';
            app.ProcessedDataLabel = uilabel(app.GridLayout2);
            app.ProcessedDataLabel.Text = 'Processed Data';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout3);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Rest Position')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'EMG Signal(amplitude in mv)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.Panel_2 = uipanel(app.GridLayout3);
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Subject 1 Hand Rest Position')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'EMG Signal(amplitude in mv)')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PreprocessingButton = uibutton(app.GridLayout6, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.ChoosefilterButtonGroup = uibuttongroup(app.GridLayout8);
            app.ChoosefilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosefilterButtonGroupSelectionChanged, true);
            app.ChoosefilterButtonGroup.Title = 'Choose filter';
            app.ButterworthButton = uiradiobutton(app.ChoosefilterButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.ButterworthButton.Value = true;
            app.MovmeanButton = uiradiobutton(app.ChoosefilterButtonGroup);
            app.MovmeanButton.Text = 'Movmean';
            app.Panel_3 = uipanel(app.GridLayout4);
            app.SubjectDropDownLabel = uilabel(app.Panel_3);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Panel_3);
            app.SubjectDropDown.Items = {'Subject 1', 'Subject 2', 'Subject 3', 'Subject 4', 'Subject 5', 'Subject 6', 'Subject 7', 'Subject 8', 'Subject 9', 'Subject 10', 'Subject 11', 'Subject 12', 'Subject 13', 'Subject 14', 'Subject 15', 'Subject 16', 'Subject 17', 'Subject 18', 'Subject 19', 'Subject 20', 'Subject 21', 'Subject 22', 'Subject 23', 'Subject 24', 'Subject 25', 'Subject 26', 'Subject 27', 'Subject 28', 'Subject 29', 'Subject 30', 'Subject 31', 'Subject 32', 'Subject 33', 'Subject 34', 'Subject 35', 'Subject 36', 'Subject 37', 'Subject 38', 'Subject 39', 'Subject 40'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Value = 'Subject 1';
            app.HandPositionDropDownLabel = uilabel(app.Panel_3);
            app.HandPositionDropDownLabel.Text = 'Hand Position';
            app.HandPositionDropDown = uidropdown(app.Panel_3);
            app.HandPositionDropDown.Items = {'hand_rest', 'extension', 'flexion', 'ulnar_deviation', 'radial_deviation', 'grip', 'abduction', 'adduction', 'supination', 'pronation'};
            app.HandPositionDropDown.ValueChangedFcn = createCallbackFcn(app, @HandPositionDropDownValueChanged, true);
            app.HandPositionDropDown.Value = 'hand_rest';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.NextButton = uibutton(app.GridLayout7, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.MyoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_preprocessing_page(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyoWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyoWaveUIFigure)
        end
    end
end