classdef main_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyoWaveUIFigure              matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        TestthemodelLabel            matlab.ui.control.Label
        Image5                       matlab.ui.control.Image
        GridLayout3                  matlab.ui.container.GridLayout
        TrainthemodelLabel           matlab.ui.control.Label
        Image4                       matlab.ui.control.Image
        GridLayout2                  matlab.ui.container.GridLayout
        PreprocessthedatafirstLabel  matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        TestModelButton              matlab.ui.control.Button
        TrainModelButton             matlab.ui.control.Button
        DataPreprocessingButton      matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        dataPreprocessingPage % Variable to connect to the data preprocessing page.
        trainModelPage % Variable to connect to the train model page.
        testModelPage % Variable to connect to the test model page.
        appName = "mainPage" % Name of this matlab app file.
    end
    
    properties (Access = private)
        dataObject
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, dataObject)
            screenSize = get(groot, 'ScreenSize');
            appWidth = 1200;
            appHeight = 700;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MyoWaveUIFigure.Position = [appX appY appWidth appHeight];
            
            app.dataObject = dataObject;

            if strcmp(callerApp.appName, "MyoWave")
                app.dataObject.files = unzip(app.dataObject.selectedZipFile);
                app.GridLayout3.RowHeight = {'0x'};
                app.GridLayout4.RowHeight = {'0x'};
            elseif strcmp(callerApp.appName, "dataPreprocessingPage")
                app.TrainModelButton.Enable = "on";           
                app.GridLayout3.RowHeight = {'1x'};
                app.GridLayout4.RowHeight = {'0x'};
            else
                app.TrainModelButton.Enable = "on";
                app.TestModelButton.Enable = "on";                
                app.GridLayout3.RowHeight = {'1x'};
                app.GridLayout4.RowHeight = {'1x'};
            end
             
        end

        % Button pushed function: DataPreprocessingButton
        function DataPreprocessingButtonPushed(app, event)
            app.dataPreprocessingPage = data_preprocessing_page(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            app.trainModelPage = train_model(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end

        % Button pushed function: TestModelButton
        function TestModelButtonPushed(app, event)
            app.testModelPage = test_model(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MyoWaveUIFigure = uifigure('Visible', 'off');
            app.MyoWaveUIFigure.Name = 'MyoWave';
            app.GridLayout = uigridlayout(app.MyoWaveUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'arrow_image.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'arrow_image.png');
            app.DataPreprocessingButton = uibutton(app.GridLayout, 'push');
            app.DataPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @DataPreprocessingButtonPushed, true);
            app.DataPreprocessingButton.Tooltip = {''};
            app.DataPreprocessingButton.Text = 'Data Preprocessing';
            app.TrainModelButton = uibutton(app.GridLayout, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.TestModelButton = uibutton(app.GridLayout, 'push');
            app.TestModelButton.ButtonPushedFcn = createCallbackFcn(app, @TestModelButtonPushed, true);
            app.TestModelButton.Enable = 'off';
            app.TestModelButton.Text = 'Test Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'info_image.jpg');
            app.PreprocessthedatafirstLabel = uilabel(app.GridLayout2);
            app.PreprocessthedatafirstLabel.Text = 'Preprocess the data first.';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Image4 = uiimage(app.GridLayout3);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'info_image.jpg');
            app.TrainthemodelLabel = uilabel(app.GridLayout3);
            app.TrainthemodelLabel.Text = 'Train the model.';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Image5 = uiimage(app.GridLayout4);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'info_image.jpg');
            app.TestthemodelLabel = uilabel(app.GridLayout4);
            app.TestthemodelLabel.Text = 'Test the model.';
            % Show the figure after all components are created
            app.MyoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_page(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyoWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyoWaveUIFigure)
        end
    end
end