classdef test_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyoWaveUIFigure          matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        SaveButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        RefreshButton            matlab.ui.control.Button
        ModelPredictionHandPositionLabel  matlab.ui.control.Label
        ActualHandPositionLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        TestExamplesLabel        matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        OverallAccuracyLabel     matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        PerformanceMetricsLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = public)
        dataObject
        mainPage
        results % to store results after testing
        appName = "testModelPage"
    end
    
    methods (Access = private)
        
        function evaluate(app)
            app.results.predictions = predict(app.dataObject.modelPackage.model, app.dataObject.modelPackage.valX);
            app.results.accuracy = sum(app.results.predictions == app.dataObject.modelPackage.valY) / length(app.results.predictions) * 100;
        end
        
        function plot_data(app)
            testSubjectId = randi([31,40], 1);
            testHandPositionIdx = randi(length(app.dataObject.handPositions), 1);
            testHandPosition = app.dataObject.handPositions(testHandPositionIdx);
            testData = app.dataObject.processedData(testSubjectId).(testHandPosition);
            predictionIdx = (testSubjectId - 31) * 10 + testHandPositionIdx;
            prediction = app.results.predictions(predictionIdx, 1);
                
            titleName = "Subject " + string(testSubjectId);
            title(app.UIAxes, titleName);

            plot(app.UIAxes, app.dataObject.time, testData', LineWidth=2);
            legend(app.UIAxes, 'Ch1', 'Ch2', 'Ch3', 'Ch4');
            app.ActualHandPositionLabel.Text = "Actual: " + string(testHandPosition);
            app.ModelPredictionHandPositionLabel.Text = "Model Prediction: " + string(app.dataObject.handPositions(prediction));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller_app, dataObject)
            screenSize = get(groot, 'ScreenSize');
            appWidth = 1200;
            appHeight = 700;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MyoWaveUIFigure.Position = [appX appY appWidth appHeight];
            
            app.dataObject = dataObject;
            evaluate(app);
            app.OverallAccuracyLabel.Text = "Overall Accuracy: " + string(app.results.accuracy) + "%";
            snoList = 1:10;
            handPositionsList = app.dataObject.handPositions;
            reshapedPredictions = reshape(app.results.predictions, [10, 10]);
            handPositionsAcc = zeros(1, 10);
            for i = 1:10
                handPositionsAcc(1, i) = sum(reshapedPredictions(i, :) == i)/length(reshapedPredictions(i,:)) * 100;
            end
            resultsTable = table(snoList', handPositionsList', handPositionsAcc');
            app.UITable.Data = resultsTable;
            
            plot_data(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            plot_data(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.mainPage = main_page(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData.model = app.dataObject.modelPackage.model;
            saveData.result = app.UITable.Data;
            % save("saveData.mat", app.saveData);
            save("saveData.mat","saveData");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyoWaveUIFigure = uifigure('Visible', 'off');
            app.MyoWaveUIFigure.Name = 'MyoWave';
            app.GridLayout = uigridlayout(app.MyoWaveUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'S.No'; 'Hand Position'; 'Accuracy (%)'};
            app.UITable.RowName = {};
            app.PerformanceMetricsLabel = uilabel(app.GridLayout2);
            app.PerformanceMetricsLabel.Text = 'Performance Metrics';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.OverallAccuracyLabel = uilabel(app.GridLayout2);
            app.OverallAccuracyLabel.Text = 'Overall Accuracy: ';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TestExamplesLabel = uilabel(app.GridLayout3);
            app.TestExamplesLabel.Text = 'Test Examples';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Panel = uipanel(app.GridLayout4);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'EMG Signal(amplitude in mv)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ActualHandPositionLabel = uilabel(app.GridLayout5);
            app.ActualHandPositionLabel.Text = 'Actual Hand Position';
            app.ModelPredictionHandPositionLabel = uilabel(app.GridLayout5);
            app.ModelPredictionHandPositionLabel.Text = 'Model Prediction Hand Position';
            app.RefreshButton = uibutton(app.GridLayout5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.BackButton = uibutton(app.GridLayout6, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SaveButton = uibutton(app.GridLayout6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.MyoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_model(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyoWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyoWaveUIFigure)
        end
    end
end