classdef train_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyoWaveUIFigure   matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        NextButton        matlab.ui.control.Button
        GridLayout2       matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        TrainModelButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        appName = "trainModel" % Description
        mainPage
        dataObject
        handPositions = ["hand_rest", "abduction", "adduction", "extension",...
            "flexion", "grip", "pronation","radial_deviation", "supination", "ulnar_deviation"];           
    end
    
    methods (Access = private)
        
        % This function train the machine learning model
        function trainModel(app)
            % Create progress dialogbox.
            dialog_box = uiprogressdlg(app.MyoWaveUIFigure, 'Title', 'Training Model', ...
                'Message', 'Processing...', ...
                'Indeterminate', 'off');

            % Create training and validation data
            app.dataObject.modelPackage.trainX = zeros(300, 48000);
            app.dataObject.modelPackage.trainY = zeros(300, 1);
            app.dataObject.modelPackage.valX = zeros(100, 48000);
            app.dataObject.modelPackage.valY = zeros(100, 1);
            
            app.dataObject.handPositions = app.handPositions;

            trainIdx = 0;
            valIdx = 0;
            
            for subjectId = 1:app.dataObject.subjects
                pause(0.09); % Simulate processing time.
                for position = 1:app.dataObject.numHandPositions
                    % Load filtered data
                    data = app.dataObject.processedData(subjectId).(app.dataObject.handPositions(position));
                    
                    % Create training and validation dataset based on the split: 75% - 25%
                    if subjectId <= 30
                        app.dataObject.modelPackage.trainX(trainIdx * app.dataObject.numHandPositions + position, :) = reshape(data, [1, 48000]);
                        app.dataObject.modelPackage.trainY(trainIdx * app.dataObject.numHandPositions + position, :) = position;
                    else
                        app.dataObject.modelPackage.valX(valIdx * app.dataObject.numHandPositions + position, :) = reshape(data, [1, 48000]);
                        app.dataObject.modelPackage.valY(valIdx * app.dataObject.numHandPositions + position, :) = position;
                    end
                end
                if subjectId <= 30
                    trainIdx = trainIdx + 1;
                else
                    valIdx = valIdx + 1;
                end
                dialog_box.Value = subjectId/40; % update the progress 0 to 1.
                dialog_box.Message = sprintf('Progress: %d%%', int16(dialog_box.Value*100));
            end
            % Fitting the model using decision tree
            app.dataObject.modelPackage.model = fitctree(app.dataObject.modelPackage.trainX, app.dataObject.modelPackage.trainY); 
            
            % Close progress bar when done.
            close(dialog_box);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, dataObject)
            screenSize = get(groot, 'ScreenSize');
            appWidth = 1200;
            appHeight = 700;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MyoWaveUIFigure.Position = [appX appY appWidth appHeight];
            
            app.dataObject = dataObject;
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % Call trainModel function to start training the model
            trainModel(app);

            % Display completion message.
            uialert(app.MyoWaveUIFigure, 'Model Training is completed !', 'Success');
            app.NextButton.Enable = "on";

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.mainPage = main_page(app, app.dataObject);
            delete(app.MyoWaveUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyoWaveUIFigure = uifigure('Visible', 'off');
            app.MyoWaveUIFigure.Name = 'MyoWave';
            app.GridLayout = uigridlayout(app.MyoWaveUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TrainModelButton = uibutton(app.GridLayout3, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.MyoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = train_model(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyoWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyoWaveUIFigure)
        end
    end
end